classdef SS_ADC_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ADCPARAMSTab                    matlab.ui.container.Tab
        defaultparam                    matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        SingleSlopeADCGUILabel          matlab.ui.control.Label
        IMPAIRMENTSPanel                matlab.ui.container.Panel
        RampNonLinearitiesPanel         matlab.ui.container.Panel
        rampnonlin_state                matlab.ui.control.CheckBox
        ye_offxcdot1e1cdot1e2cdotxe3cdotx2Label  matlab.ui.control.Label
        e3                              matlab.ui.control.NumericEditField
        a0Label_3                       matlab.ui.control.Label
        e2                              matlab.ui.control.NumericEditField
        a0Label_2                       matlab.ui.control.Label
        e1                              matlab.ui.control.NumericEditField
        a11fordefaultLabel              matlab.ui.control.Label
        eoff                            matlab.ui.control.NumericEditField
        a0Label                         matlab.ui.control.Label
        RampNoisePanel                  matlab.ui.container.Panel
        randomuniformVnoiseVnoiseLabel  matlab.ui.control.Label
        LSBCALCULATIONPanel             matlab.ui.container.Panel
        LSB4VLabel                      matlab.ui.control.Label
        Q4                              matlab.ui.control.Label
        Q                               matlab.ui.control.Label
        LSBfracFS2NBitsVLabel           matlab.ui.control.Label
        Vnoise_ramp                     matlab.ui.control.NumericEditField
        NoiseLabel                      matlab.ui.control.Label
        ramp_noise_state                matlab.ui.control.CheckBox
        ComparatorNoisePanel            matlab.ui.container.Panel
        randomuniformVnoiseVnoiseLabel_2  matlab.ui.control.Label
        RecommendationsPanel            matlab.ui.container.Panel
        ComparatornoiseRampNoiseshouldnotbegreaterthanBinSize4Label  matlab.ui.control.Label
        Vof_comparator                  matlab.ui.control.NumericEditField
        NoiseLabel_3                    matlab.ui.control.Label
        Vnoise_comparator               matlab.ui.control.NumericEditField
        NoiseLabel_2                    matlab.ui.control.Label
        comparator_noise_state          matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        ADCBASICPARAMETERSPanel         matlab.ui.container.Panel
        fclk                            matlab.ui.control.NumericEditField
        ClockfrequencyfclkLabel         matlab.ui.control.Label
        FS                              matlab.ui.control.NumericEditField
        FullScale0FSLabel               matlab.ui.control.Label
        B                               matlab.ui.control.NumericEditField
        ResolutionNBitsEditFieldLabel   matlab.ui.control.Label
        STATICTESTTab                   matlab.ui.container.Tab
        RampbasedhistogramtestGainOffseterrorINLDNLmissingcodesLabel  matlab.ui.control.Label
        simstate                        matlab.ui.control.Label
        RampslopefracFS1fclk2NBitsMLabel  matlab.ui.control.Label
        RESULTSPanel_2                  matlab.ui.container.Panel
        DNLSol                          matlab.ui.control.Label
        INLSol                          matlab.ui.control.Label
        Offsetsol                       matlab.ui.control.Label
        Gainsol                         matlab.ui.control.Label
        mathbfINL_maxLSBLabel           matlab.ui.control.Label
        mathbfDNL_maxLSBLabel           matlab.ui.control.Label
        textbfOffseterrorVLabel         matlab.ui.control.Label
        textbfGainerrorLabel            matlab.ui.control.Label
        errorlabel                      matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        SimulationResultsTab            matlab.ui.container.Tab
        transchar                       matlab.ui.control.UIAxes
        CORRECTEDCharacteristicTab      matlab.ui.container.Tab
        correctedgraph                  matlab.ui.control.UIAxes
        numperslope                     matlab.ui.control.NumericEditField
        NumberofLabel                   matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        simulatestat                    matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        DNL                             matlab.ui.control.UIAxes
        INL                             matlab.ui.control.UIAxes
        DYNAMICTESTTab                  matlab.ui.container.Tab
        ComparatorregenerativepolePanel  matlab.ui.container.Panel
        taucomp                         matlab.ui.control.EditField
        tau_comps1Label                 matlab.ui.control.Label
        regpole_state                   matlab.ui.control.CheckBox
        ClockJitterPanel                matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        RMSValueLabel                   matlab.ui.control.Label
        ap_jitter                       matlab.ui.control.NumericEditField
        Numberofpointstemporalsequence2NNLabel_2  matlab.ui.control.Label
        dyn_impairments_state           matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        psButton                        matlab.ui.control.RadioButton
        nsButton                        matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        fs                              matlab.ui.control.Label
        textbfSamplingfrequencyfsfracfclk2NBitstextbfHzLabel  matlab.ui.control.Label
        SinewavebasedtestforevaluatingTHDSNRSNDRENOBPsigPnoisePHDLabel  matlab.ui.control.Label
        simstate_2                      matlab.ui.control.Label
        RESULTSPanel                    matlab.ui.container.Panel
        text10                          matlab.ui.control.Label
        text9                           matlab.ui.control.Label
        text8                           matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        text1                           matlab.ui.control.Label
        FFTParametersPanel              matlab.ui.container.Panel
        Windowfunction                  matlab.ui.control.DropDown
        WindowfunctionDropDownLabel     matlab.ui.control.Label
        span_bins                       matlab.ui.control.NumericEditField
        NbinsaroundcentralbinforpowercomputationLabel  matlab.ui.control.Label
        N                               matlab.ui.control.NumericEditField
        Numberofpointstemporalsequence2NNLabel  matlab.ui.control.Label
        SinusoidalSignalPanel           matlab.ui.container.Panel
        NoiseLabel_5                    matlab.ui.control.Label
        NoiseLabel_4                    matlab.ui.control.Label
        fin                             matlab.ui.control.EditField
        FrequencyfundamentaltonefinHzLabel  matlab.ui.control.Label
        white_noise                     matlab.ui.control.EditField
        Ain                             matlab.ui.control.EditField
        AmplitudeFundamentalToneVLabel  matlab.ui.control.Label
        Xof                             matlab.ui.control.EditField
        OffsetVLabel                    matlab.ui.control.Label
        white_noise_state               matlab.ui.control.CheckBox
        ndto5thharmonicsamplitudesVPanel  matlab.ui.container.Panel
        Ah5                             matlab.ui.control.EditField
        Ah5EditFieldLabel               matlab.ui.control.Label
        Ah3                             matlab.ui.control.EditField
        Ah3EditFieldLabel               matlab.ui.control.Label
        Ah4                             matlab.ui.control.EditField
        Ah4EditFieldLabel               matlab.ui.control.Label
        Ah2                             matlab.ui.control.EditField
        Ah2EditFieldLabel               matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        simulatedyn                     matlab.ui.control.Button
        HarmonicsPanel                  matlab.ui.container.Panel
        text2                           matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        text4                           matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        text6                           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        spectfig                        matlab.ui.control.UIAxes
        PARAMETRICSIMTab                matlab.ui.container.Tab
        simul_state                     matlab.ui.control.Label
        ImpairmentSelectionButtonGroup  matlab.ui.container.ButtonGroup
        StopLabel                       matlab.ui.control.Label
        StepLabel                       matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        stop                            matlab.ui.control.NumericEditField
        step                            matlab.ui.control.NumericEditField
        start                           matlab.ui.control.NumericEditField
        Noisecomp                       matlab.ui.control.RadioButton
        Noiseramp                       matlab.ui.control.RadioButton
        e2_2                            matlab.ui.control.RadioButton
        e_off_2                         matlab.ui.control.RadioButton
        e1_2                            matlab.ui.control.RadioButton
        e3_2                            matlab.ui.control.RadioButton
        RampLabel                       matlab.ui.control.Label
        Offsetcomp                      matlab.ui.control.RadioButton
        Panel_5                         matlab.ui.container.Panel
        Panel_6                         matlab.ui.container.Panel
        ComparatorLabel                 matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        simulateparam                   matlab.ui.control.Button
        paramfig                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           start_simulink
        end

        % Button pushed function: simulatestat
        function simulatestatButtonPushed(app, event)
           cla(app.transchar,"reset");  
           cla(app.DNL,"reset"); 
           cla(app.INL,"reset"); 
           cla(app.correctedgraph,"reset");
           app.errorlabel.Text=" ";
           app.Gainsol.Text= " ";
           app.Offsetsol.Text= " ";
           app.INLSol.Text= " ";
           app.DNLSol.Text=  " ";
           app.simstate.Text = "Simulation in progress...";
           pause(0.01)
           %% STANDARD VALUES FOR NOERROR
 % INPUT TYPE:
input_type = 1;
initial_output=1;
clock_type = 2;

% 1 --> Ramp -> Static characterization
% 2 --> Constant -> Number conversion. Interval -> [0, FS]
const=0.8; %default value
% 3 --> Sine -> Dynamic characterization

% Sinusoidal input signal (here for no error)
Ain=(0.9*app.FS.Value)/2; % Amplitude of fundamental tone (in V)
Xof=app.FS.Value/2; % Offset
Ah2=1*sqrt(2)*3e-5; % Amplitude of 2nd harmonic (in V)
Ah3=1*sqrt(2)*5e-4; % Amplitude of 3rd harmonic (in V)
Ah4=1*sqrt(2)*3e-5; % Amplitude of 4th harmonic (in V)
Ah5=1*sqrt(2)*1e-4; % Amplitude of 5th harmonic (in V)
noise_Vrms=1*1e-4;  % White noise (std_noise)

% Clock type. (here for no error)

mean_alpha = 0;
mean_beta = 0.5;
OSR = 2;
Ts = 1/(app.fclk.Value);
sigma_alpha = 0.01 * Ts; 
sigma_beta = 0.01 * Ts; 
tphase = 1/app.fclk.Value;
tau = tphase/1000;

%% We asign real values to MATLAB workspace

assignin('base','input_type',input_type)
assignin('base','initial_output',initial_output)

FS = app.FS.Value; assignin('base','FS',FS)

B = app.B.Value; assignin('base','B',B)

M = app.numperslope.Value*2^app.B.Value; assignin('base','M',M)

% app.Q.Text = num2str(app.FS.Value/(2^app.B.Value));

Q = app.FS.Value/(2^app.B.Value); assignin('base','Q',Q)

fclk =app.fclk.Value; assignin('base','fclk',fclk)

assignin('base','const',const)

% SINE INFORMATION

assignin('base','Ain',Ain)
assignin('base','Xof',Xof)
assignin('base','Ah2',Ah2)
assignin('base','Ah3',Ah3)
assignin('base','Ah4',Ah4)
assignin('base','Ah5',Ah5)
assignin('base','noise_Vrms',noise_Vrms)


% CLOCK INFORMATION
assignin('base','clock_type',clock_type)
assignin('base','mean_alpha',mean_alpha)
assignin('base','mean_beta',mean_beta)
assignin('base','OSR',OSR)
assignin('base','Ts',Ts)
assignin('base','sigma_alpha',sigma_alpha)
assignin('base','sigma_beta',sigma_beta)
assignin('base','tphase',tphase)
assignin('base','tau',tau)

% RAMP GENERATOR
if app.rampnonlin_state.Value ==1
    eoff = app.eoff.Value; 
    e1= (app.e1.Value/100); 
    e2= (app.e2.Value/100); 
    e3 = (app.e3.Value/100); 
else
    eoff=0;
    e1=0;
    e2=0;
    e3=0;
end

assignin('base','eoff', eoff); assignin('base','e1', e1)
assignin('base','e2',e2); assignin('base','e3',e3)

% RAMP NOISE
ramp_noise_state=app.ramp_noise_state.Value; assignin('base','ramp_noise_state',ramp_noise_state) %#ok<*ADPROPLC>

if ramp_noise_state == 1
    Vnoise_ramp=app.Vnoise_ramp.Value;
    if Vnoise_ramp==0
        Vnoise_ramp = 1e-10; % FOR NOT ERROR
    end
    assignin('base','Vnoise_ramp',Vnoise_ramp)
else
    Vnoise_ramp = 1e-10; assignin('base','Vnoise_ramp',Vnoise_ramp)
end
    SEED1 = round(rand*20000, 0); assignin('base','SEED1',SEED1)

% COMPARATOR NOISE
comparator_noise_state = app.comparator_noise_state.Value; assignin('base','comparator_noise_state',comparator_noise_state)
if comparator_noise_state == 1
    Vnoise_comparator = app.Vnoise_comparator.Value; 
    if Vnoise_comparator==0
        Vnoise_comparator = 1e-10; % FOR NOT ERROR
    end
    assignin('base','Vnoise_comparator',Vnoise_comparator)
    Vof_comparator = app.Vof_comparator.Value;  assignin('base','Vof_comparator',Vof_comparator)
else
    Vnoise_comparator = 1e-10; assignin('base','Vnoise_comparator',Vnoise_comparator)
    Vof_comparator = 1e-20; assignin('base','Vof_comparator',Vof_comparator)
end

SEED2 = round(rand*10000, 0); assignin('base','SEED2',SEED2)

% INITIAL & FINAL VALUE

if app.comparator_noise_state>0 || eoff <0
    if Vof_comparator <0        
        initial_value = eoff + Vof_comparator - Q;
    else
        initial_value = eoff - Q;
    end 
else
    initial_value = 0;
end


if app.comparator_noise_state>0
    final_value = Vof_comparator+ eoff+(1+e1)*FS*(1+e2*FS+e3*FS)+FS/4;

else
    final_value = eoff+(1+e1)*FS*(1+e2*FS+e3*FS)+FS/4;
end

if final_value <FS
    final_value = FS+FS/4;
end

assignin('base','initial_value',initial_value)
assignin('base','final_value',final_value)

% FREQUENCIES (not important) AND SIMULATION TIME
fin=1; fs=1;

assignin('base','fin',fin)
assignin('base','fs',fs)

simtime = 10^15;
assignin('base','simtime',simtime)

%% RUN PROGRAM
run('static_charac.m')

%%
app.simstate.Text = "";

        end

        % Button pushed function: simulatedyn
        function simulatedynButtonPushed(app, event)
          cla(app.spectfig,"reset"); 
          app.simstate_2.Text = "Simulation in progress...";
          pause(0.001)
            %% INPUT TYPE:

input_type = 3;
% 1 --> Ramp -> Static characterization
% 2 --> Constant -> Number conversion. Interval -> [0, FS]
const=0.8; %default value
% 3 --> Sine -> Dynamic characterization

%% Initialization
% rng(0,'twister')
initial_output=1;
         
%% We asign real values to MATLAB workspace
assignin('base','input_type',input_type)
assignin('base','initial_output',initial_output)

FS = app.FS.Value; assignin('base','FS',FS)

B = app.B.Value; assignin('base','B',B)

M = app.numperslope.Value*2^app.B.Value; assignin('base','M',M)
    

% app.Q.Text = num2str(app.FS.Value/(2^app.B.Value));

Q = app.FS.Value/(2^app.B.Value); assignin('base','Q',Q)

fclk =app.fclk.Value; assignin('base','fclk',fclk)

assignin('base','const',const)

if app.rampnonlin_state.Value ==1
    eoff = app.eoff.Value; 
    e1= (app.e1.Value/100); 
    e2= (app.e2.Value/100); 
    e3 = (app.e3.Value/100); 
else
    eoff=0;
    e1=0;
    e2=0;
    e3=0;
end

assignin('base','eoff', eoff); assignin('base','e1', e1)
assignin('base','e2',e2); assignin('base','e3',e3)

% RAMP NOISE
ramp_noise_state=app.ramp_noise_state.Value; assignin('base','ramp_noise_state',ramp_noise_state) %#ok<*ADPROPLC>

if ramp_noise_state == 1
    Vnoise_ramp=app.Vnoise_ramp.Value;
    if Vnoise_ramp==0
        Vnoise_ramp = 1e-10; % FOR NOT ERROR
    end
    assignin('base','Vnoise_ramp',Vnoise_ramp)
else
    Vnoise_ramp = 1e-10; assignin('base','Vnoise_ramp',Vnoise_ramp)
end
    SEED1 = round(rand*20000, 0); assignin('base','SEED1',SEED1)

% COMPARATOR NOISE
comparator_noise_state = app.comparator_noise_state.Value; assignin('base','comparator_noise_state',comparator_noise_state)
if comparator_noise_state == 1
    Vnoise_comparator = app.Vnoise_comparator.Value; 
    if Vnoise_comparator==0
        Vnoise_comparator = 1e-10; % FOR NOT ERROR
    end
    assignin('base','Vnoise_comparator',Vnoise_comparator)
    Vof_comparator = app.Vof_comparator.Value;  assignin('base','Vof_comparator',Vof_comparator)
else
    Vnoise_comparator = 1e-10; assignin('base','Vnoise_comparator',Vnoise_comparator)
    Vof_comparator = 1e-20; assignin('base','Vof_comparator',Vof_comparator)
end

SEED2 = round(rand*10000, 0); assignin('base','SEED2',SEED2)

% INITIAL & FINAL VALUE

if app.comparator_noise_state>0 || eoff <0
    if Vof_comparator <0        
        initial_value = eoff + Vof_comparator - Q;
    else
        initial_value = eoff - Q;
    end 
else
    initial_value = 0;
end


if app.comparator_noise_state>0
    final_value = Vof_comparator+ eoff+e1*FS+e2*FS^2+e3*FS^3+FS/4;

else
    final_value = eoff+e1*FS+e2*FS^2+e3*FS^3+FS/4;          
end

if final_value <FS
    final_value = FS+FS/4;
end

assignin('base','initial_value',initial_value)
assignin('base','final_value',final_value)


% FROM DYNAMIC CHARACTERIZATION SPECIFIC
% SINE INFORMATION
Ain = eval(app.Ain.Value); assignin('base','Ain',Ain)
Xof = eval(app.Xof.Value);assignin('base','Xof',Xof)
Ah2 = eval(app.Ah2.Value);assignin('base','Ah2',Ah2)
Ah3 = eval(app.Ah3.Value);assignin('base','Ah3',Ah3)
Ah4 = eval(app.Ah4.Value);assignin('base','Ah4',Ah4)
Ah5 = eval(app.Ah5.Value);assignin('base','Ah5',Ah5)

if app.white_noise_state.Value == 1
    noise_Vrms=eval(app.white_noise.Value);  % White noise (std_noise)
    assignin('base','noise_Vrms',noise_Vrms)
else
    assignin('base','noise_Vrms',0);
end

% FREQUENCIES ETC
fs = fclk/(2^B); assignin('base','fs',fs)
fin = eval(app.fin.Value); 
N = app.N.Value; assignin('base','N',2^N)
span_bins = app.span_bins.Value; assignin('base','span_bins',span_bins)

fin = fs/(2^N)*round((2^N)*fin/fs); assignin('base','fin',fin)

simtime= (2^N)/fs; assignin('base','simtime',simtime)


% CLOCK INFORMATION

% JITTER VALUES

ap_jitter = app.ap_jitter.Value;

if double(app.psButton.Value) == 1
    ap_jitter = ap_jitter * 10^-12;
elseif double(app.nsButton.Value) == 1
    ap_jitter = ap_jitter * 10^-9;
end

if app.dyn_impairments_state.Value == 0
    clock_type = 2;
else
    clock_type = 1;
end

assignin('base','dyn_impairments_state', app.dyn_impairments_state.Value)
assignin('base','clock_type', clock_type)
assignin('base','ap_jitter',ap_jitter)

% METASTABILITY VALUES

if app.regpole_state.Value == 0
    tphase = 1/fclk;
    tau = tphase/100000000;         % We emulate time constant to be very high.
else
    tphase = 1/fclk;
    tau = eval(app.taucomp.Value);
end

assignin('base','regpole_state',app.regpole_state.Value)
assignin('base','tphase',tphase)
assignin('base','tau',tau)

%% RUN PROGRAM
run('dynamic_charac.m')
% run('withoutGUI_forsim_dynamic_charac.m')
%% 
app.simstate_2.Text = " ";         
        end

        % Value changed function: B
        function BValueChanged(app, event)
            app.B.Value = round(app.B.Value);
            app.Q.Text = num2str(app.FS.Value/(2^app.B.Value));
            app.Q4.Text = num2str(app.FS.Value/(2^app.B.Value)/4);   
            app.fs.Text=num2str(app.fclk.Value/(2^(app.B.Value)));
        end

        % Value changed function: FS
        function FSValueChanged(app, event)
            app.Q.Text = num2str(app.FS.Value/(2^app.B.Value));
            app.Q4.Text = num2str(app.FS.Value/(2^app.B.Value)/4);
        end

        % Value changed function: fclk
        function fclkValueChanged(app, event)
            app.fs.Text=num2str(app.fclk.Value/(2^(app.B.Value)));
        end

        % Button pushed function: simulateparam
        function simulateparamButtonPushed(app, event)
          cla(app.paramfig,"reset"); 
      %%%   app.simstate_2.Text = "Simulation in progress...";
          pause(0.001)
            %% INPUT TYPE:
input_type = 3;

% 1 --> Ramp -> Static characterization
% 2 --> Constant -> Number conversion. Interval -> [0, FS]
const=0.8; %default value
% 3 --> Sine -> Dynamic characterization

%% Initialization
% rng(0,'twister')
initial_output=1;
         
%% We asign real values to MATLAB workspace

assignin('base','input_type',input_type)
assignin('base','initial_output',initial_output)

FS = app.FS.Value; assignin('base','FS',FS)

B = app.B.Value; assignin('base','B',B)

M = app.numperslope.Value*2^app.B.Value; assignin('base','M',M)

Q = app.FS.Value/(2^app.B.Value); assignin('base','Q',Q)

fclk =app.fclk.Value; assignin('base','fclk',fclk)

assignin('base','const',const)

if app.rampnonlin_state.Value ==1
    eoff = app.eoff.Value; 
    e1= (app.e1.Value/100); 
    e2= (app.e2.Value/100); 
    e3 = (app.e3.Value/100); 
else
    eoff=0;
    e1=0;
    e2=0;
    e3=0;
end

assignin('base','eoff', eoff); assignin('base','e1', e1)
assignin('base','e2',e2); assignin('base','e3',e3)

% RAMP NOISE
ramp_noise_state=app.ramp_noise_state.Value; assignin('base','ramp_noise_state',ramp_noise_state) %#ok<*ADPROPLC>

if ramp_noise_state == 1
    Vnoise_ramp=app.Vnoise_ramp.Value;
    if Vnoise_ramp==0
        Vnoise_ramp = 1e-10; % FOR NOT ERROR
    end
    assignin('base','Vnoise_ramp',Vnoise_ramp)
else
    Vnoise_ramp = 1e-10; assignin('base','Vnoise_ramp',Vnoise_ramp)
end
    SEED1 = round(rand*20000, 0); assignin('base','SEED1',SEED1)

% COMPARATOR NOISE
comparator_noise_state = app.comparator_noise_state.Value; assignin('base','comparator_noise_state',comparator_noise_state)
if comparator_noise_state == 1
    Vnoise_comparator = app.Vnoise_comparator.Value; 
    if Vnoise_comparator==0
        Vnoise_comparator = 1e-10; % FOR NOT ERROR
    end
    assignin('base','Vnoise_comparator',Vnoise_comparator)
    Vof_comparator = app.Vof_comparator.Value;  assignin('base','Vof_comparator',Vof_comparator)
else
    Vnoise_comparator = 1e-10; assignin('base','Vnoise_comparator',Vnoise_comparator)
    Vof_comparator = 1e-20; assignin('base','Vof_comparator',Vof_comparator)
end

SEED2 = round(rand*10000, 0); assignin('base','SEED2',SEED2)

% INITIAL & FINAL VALUE

if app.comparator_noise_state>0 || eoff <0
    if Vof_comparator <0        
        initial_value = eoff + Vof_comparator - Q;
    else
        initial_value = eoff - Q;
    end 
else
    initial_value = 0;
end


if app.comparator_noise_state>0
    final_value = Vof_comparator+ eoff+e1*FS+e2*FS^2+e3*FS^3+FS/4;

else
    final_value = eoff+e1*FS+e2*FS^2+e3*FS^3+FS/4;          
end

if final_value <FS
    final_value = FS+FS/4;
end

assignin('base','initial_value',initial_value)
assignin('base','final_value',final_value)


% SPECIFIC FROM DYNAMIC CHARACTERIZATION 
% SINE INFORMATION
Ain = eval(app.Ain.Value); assignin('base','Ain',Ain)
Xof = eval(app.Xof.Value);assignin('base','Xof',Xof)
Ah2 = eval(app.Ah2.Value);assignin('base','Ah2',Ah2)
Ah3 = eval(app.Ah3.Value);assignin('base','Ah3',Ah3)
Ah4 = eval(app.Ah4.Value);assignin('base','Ah4',Ah4)
Ah5 = eval(app.Ah5.Value);assignin('base','Ah5',Ah5)

if app.white_noise_state.Value == 1
    noise_Vrms=eval(app.white_noise.Value);  % White noise (std_noise)
    assignin('base','noise_Vrms',noise_Vrms)
else
    assignin('base','noise_Vrms',0);
end

% FREQUENCIES ETC
fs = fclk/(2^B); assignin('base','fs',fs)
fin = eval(app.fin.Value); 
N = app.N.Value; assignin('base','N',2^N)
span_bins = app.span_bins.Value; assignin('base','span_bins',span_bins)

fin = fs/(2^N)*round((2^N)*fin/fs); assignin('base','fin',fin)

simtime= (2^N)/fs; assignin('base','simtime',simtime)


%% IMPAIRMENT SELECTION AND PARMETRIC SIMULATION

run('imp_selection.m')


        end

        % Button pushed function: defaultparam
        function defaultparamPushed(app, event)
         app.B.Value = 8;
         app.FS.Value = 1;
         app.fclk.Value = 1e+06;
         app.eoff.Value = 0;
         app.e1.Value = 0;
         app.e2.Value = 0;
         app.e3.Value = 0;
         app.rampnonlin_state.Value = 1;
         app.ramp_noise_state.Value = 1;
         app.Vnoise_ramp.Value = 0.001;
         app.comparator_noise_state.Value = 1;
         app.Vnoise_comparator.Value = 0.001;
         app.Vof_comparator.Value = 0;

         app.numperslope.Value = 5;

         app.Ain.Value = '0.9*(FS/2)';
         app.fin.Value = '0.98* (1/2) * fs/5';
         app.Ah2.Value = '1*sqrt(2)*3e-5';
         app.Ah3.Value = '1*sqrt(2)*5e-4';
         app.Ah4.Value = '1*sqrt(2)*3e-5';
         app.Ah5.Value = '1*sqrt(2)*1e-4';
         app.Xof.Value = 'FS/2';
         app.white_noise_state.Value = 1;
         app.white_noise.Value = '1*1e-4';
         app.N.Value = 11;
         app.span_bins.Value = 10;
         app.Windowfunction.Value = 'Hanning';

         % cla(app.paramfig,"reset");
         % cla(app.transchar,"reset");
         % cla(app.INL,"reset");
         % cla(app.DNL,"reset");

         % app.Gainsol.Text = "";
         % app.Offsetsol.Text = "";
         % app.INLSol.Text = "";
         % app.DNLSol.Text = "";
         % 
         % app.text2.Text = "";
         % app.text3.Text = "";
         % app.text4.Text = "";
         % app.text5.Text = "";
         % app.text6.Text = "";
         % app.text10.Text = "";
         % app.text9.Text = "";
         % app.text8.Text = "";
         % app.text7.Text = "";
         % app.text1.Text = "";

         app.B.Value = round(app.B.Value);
         app.Q.Text = num2str(app.FS.Value/(2^app.B.Value));
         app.Q4.Text = num2str(app.FS.Value/(2^app.B.Value)/4);   
         app.fs.Text=num2str(app.fclk.Value/(2^(app.B.Value)));

         app.start.Value = 0;
         app.step.Value = 0.1;
         app.stop.Value = 0.5;
         app.e_off_2.Value = 1;
         % cla(app.paramfig,"reset");
        end

        % Callback function: not associated with a component
        function nrValueChanged(app, event)
            app.jittermin.Text = num2str(1/(app.fclk.Value *app.nr.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ADCPARAMSTab = uitab(app.TabGroup);
            app.ADCPARAMSTab.Title = 'ADC PARAMS';
            app.ADCBASICPARAMETERSPanel = uipanel(app.ADCPARAMSTab);
            app.ADCBASICPARAMETERSPanel.Title = 'ADC BASIC PARAMETERS';
            app.ResolutionNBitsEditFieldLabel = uilabel(app.ADCBASICPARAMETERSPanel);
            app.ResolutionNBitsEditFieldLabel.Interpreter = 'latex';
            app.ResolutionNBitsEditFieldLabel.Text = 'Resolution (NBits)';
            app.B = uieditfield(app.ADCBASICPARAMETERSPanel, 'numeric');
            app.B.ValueChangedFcn = createCallbackFcn(app, @BValueChanged, true);
            app.B.Value = 8;
            app.FullScale0FSLabel = uilabel(app.ADCBASICPARAMETERSPanel);
            app.FullScale0FSLabel.Interpreter = 'latex';
            app.FullScale0FSLabel.Text = 'Full Scale, [0, FS] [V]';
            app.FS = uieditfield(app.ADCBASICPARAMETERSPanel, 'numeric');
            app.FS.ValueChangedFcn = createCallbackFcn(app, @FSValueChanged, true);
            app.FS.Value = 1;
            app.ClockfrequencyfclkLabel = uilabel(app.ADCBASICPARAMETERSPanel);
            app.ClockfrequencyfclkLabel.Interpreter = 'latex';
            app.ClockfrequencyfclkLabel.Text = 'Clock frequency [Hz] (fclk)';
            app.fclk = uieditfield(app.ADCBASICPARAMETERSPanel, 'numeric');
            app.fclk.ValueChangedFcn = createCallbackFcn(app, @fclkValueChanged, true);
            app.fclk.Value = 100000000;
            app.Image = uiimage(app.ADCPARAMSTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LOGO.png');
            app.IMPAIRMENTSPanel = uipanel(app.ADCPARAMSTab);
            app.IMPAIRMENTSPanel.Title = 'IMPAIRMENTS';
            app.ComparatorNoisePanel = uipanel(app.IMPAIRMENTSPanel);
            app.ComparatorNoisePanel.Title = 'Comparator Noise';
            app.comparator_noise_state = uicheckbox(app.ComparatorNoisePanel);
            app.comparator_noise_state.Text = 'ENABLED';
            app.comparator_noise_state.Value = true;
            app.NoiseLabel_2 = uilabel(app.ComparatorNoisePanel);
            app.NoiseLabel_2.Interpreter = 'latex';
            app.NoiseLabel_2.Text = 'Vnoise [V] ';
            app.Vnoise_comparator = uieditfield(app.ComparatorNoisePanel, 'numeric');
            app.Vnoise_comparator.Value = 0.001;
            app.NoiseLabel_3 = uilabel(app.ComparatorNoisePanel);
            app.NoiseLabel_3.Interpreter = 'latex';
            app.NoiseLabel_3.Text = 'Offset [V]';
            app.Vof_comparator = uieditfield(app.ComparatorNoisePanel, 'numeric');
            app.RecommendationsPanel = uipanel(app.ComparatorNoisePanel);
            app.RecommendationsPanel.Title = 'Recommendations';
            app.ComparatornoiseRampNoiseshouldnotbegreaterthanBinSize4Label = uilabel(app.RecommendationsPanel);
            app.ComparatornoiseRampNoiseshouldnotbegreaterthanBinSize4Label.Interpreter = 'latex';
            app.ComparatornoiseRampNoiseshouldnotbegreaterthanBinSize4Label.Text = {'1) If Comparator Noise + Ramp Noise > 0.5 LSB, errors may occur while computing the static IO curve.'; ''; '2) If NBits>10, the simulation time may be too long.'; ''; '3) If e2/e3 take large values, missing codes may occur.'};
            app.randomuniformVnoiseVnoiseLabel_2 = uilabel(app.ComparatorNoisePanel);
            app.randomuniformVnoiseVnoiseLabel_2.Interpreter = 'latex';
            app.randomuniformVnoiseVnoiseLabel_2.Text = 'random ∈ uniform(-Vnoise, Vnoise)';
            app.RampNoisePanel = uipanel(app.IMPAIRMENTSPanel);
            app.RampNoisePanel.Title = 'Ramp Noise';
            app.ramp_noise_state = uicheckbox(app.RampNoisePanel);
            app.ramp_noise_state.Text = 'ENABLED';
            app.ramp_noise_state.Value = true;
            app.NoiseLabel = uilabel(app.RampNoisePanel);
            app.NoiseLabel.Interpreter = 'latex';
            app.NoiseLabel.Text = 'Vnoise [V] ';
            app.Vnoise_ramp = uieditfield(app.RampNoisePanel, 'numeric');
            app.Vnoise_ramp.Value = 0.001;
            app.LSBCALCULATIONPanel = uipanel(app.RampNoisePanel);
            app.LSBCALCULATIONPanel.Title = 'LSB CALCULATION';
            app.LSBfracFS2NBitsVLabel = uilabel(app.LSBCALCULATIONPanel);
            app.LSBfracFS2NBitsVLabel.Interpreter = 'latex';
            app.LSBfracFS2NBitsVLabel.Text = 'LSB = $ \frac{FS}{2^{NBits}} [V] = $';
            app.Q = uilabel(app.LSBCALCULATIONPanel);
            app.Q.Text = '0.0039062';
            app.Q4 = uilabel(app.LSBCALCULATIONPanel);
            app.Q4.Text = '0.00097656';
            app.LSB4VLabel = uilabel(app.LSBCALCULATIONPanel);
            app.LSB4VLabel.Interpreter = 'latex';
            app.LSB4VLabel.Text = 'LSB/4 [V] =';
            app.randomuniformVnoiseVnoiseLabel = uilabel(app.RampNoisePanel);
            app.randomuniformVnoiseVnoiseLabel.Interpreter = 'latex';
            app.randomuniformVnoiseVnoiseLabel.Text = 'random ∈ uniform(-Vnoise, Vnoise)';
            app.RampNonLinearitiesPanel = uipanel(app.IMPAIRMENTSPanel);
            app.RampNonLinearitiesPanel.Title = 'Ramp Non-Linearities';
            app.a0Label = uilabel(app.RampNonLinearitiesPanel);
            app.a0Label.Interpreter = 'tex';
            app.a0Label.Text = 'e_off [V]';
            app.eoff = uieditfield(app.RampNonLinearitiesPanel, 'numeric');
            app.a11fordefaultLabel = uilabel(app.RampNonLinearitiesPanel);
            app.a11fordefaultLabel.Interpreter = 'latex';
            app.a11fordefaultLabel.Text = 'e1 [\%]';
            app.e1 = uieditfield(app.RampNonLinearitiesPanel, 'numeric');
            app.a0Label_2 = uilabel(app.RampNonLinearitiesPanel);
            app.a0Label_2.Interpreter = 'latex';
            app.a0Label_2.Text = 'e2 [\%]';
            app.e2 = uieditfield(app.RampNonLinearitiesPanel, 'numeric');
            app.a0Label_3 = uilabel(app.RampNonLinearitiesPanel);
            app.a0Label_3.Interpreter = 'latex';
            app.a0Label_3.Text = 'e3 [\%]';
            app.e3 = uieditfield(app.RampNonLinearitiesPanel, 'numeric');
            app.ye_offxcdot1e1cdot1e2cdotxe3cdotx2Label = uilabel(app.RampNonLinearitiesPanel);
            app.ye_offxcdot1e1cdot1e2cdotxe3cdotx2Label.Interpreter = 'latex';
            app.ye_offxcdot1e1cdot1e2cdotxe3cdotx2Label.Text = '$y = e\_off + x\cdot(1+e1)\cdot(1+e2\cdot x+e3\cdot x^2)$';
            app.rampnonlin_state = uicheckbox(app.RampNonLinearitiesPanel);
            app.rampnonlin_state.Text = 'ENABLED';
            app.rampnonlin_state.Value = true;
            app.SingleSlopeADCGUILabel = uilabel(app.ADCPARAMSTab);
            app.SingleSlopeADCGUILabel.Text = 'Single-Slope ADC GUI';
            app.Image2 = uiimage(app.ADCPARAMSTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'schematic_new.png');
            app.defaultparam = uibutton(app.ADCPARAMSTab, 'push');
            app.defaultparam.ButtonPushedFcn = createCallbackFcn(app, @defaultparamPushed, true);
            app.defaultparam.Text = 'Default Parameters';
            app.STATICTESTTab = uitab(app.TabGroup);
            app.STATICTESTTab.Title = 'STATIC TEST';
            app.INL = uiaxes(app.STATICTESTTab);
            title(app.INL, 'INL')
            xlabel(app.INL, 'Code')
            ylabel(app.INL, 'INL(k)')
            app.DNL = uiaxes(app.STATICTESTTab);
            title(app.DNL, 'DNL')
            xlabel(app.DNL, 'Code')
            ylabel(app.DNL, 'DNL(k)')
            app.Panel_3 = uipanel(app.STATICTESTTab);
            app.simulatestat = uibutton(app.STATICTESTTab, 'push');
            app.simulatestat.ButtonPushedFcn = createCallbackFcn(app, @simulatestatButtonPushed, true);
            app.simulatestat.Text = 'SIMULATE';
            app.Image_2 = uiimage(app.STATICTESTTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'LOGO.png');
            app.NumberofLabel = uilabel(app.STATICTESTTab);
            app.NumberofLabel.Interpreter = 'latex';
            app.NumberofLabel.Text = 'Impacts per code, M =';
            app.numperslope = uieditfield(app.STATICTESTTab, 'numeric');
            app.numperslope.Value = 5;
            app.TabGroup2 = uitabgroup(app.STATICTESTTab);
            app.SimulationResultsTab = uitab(app.TabGroup2);
            app.SimulationResultsTab.Title = 'Simulation Results';
            app.transchar = uiaxes(app.SimulationResultsTab);
            title(app.transchar, 'STATIC CHARACTERISTIC')
            xlabel(app.transchar, 'AI')
            ylabel(app.transchar, 'DO')
            zlabel(app.transchar, 'Z')
            app.CORRECTEDCharacteristicTab = uitab(app.TabGroup2);
            app.CORRECTEDCharacteristicTab.Title = 'CORRECTED Characteristic';
            app.correctedgraph = uiaxes(app.CORRECTEDCharacteristicTab);
            title(app.correctedgraph, 'STATIC CHARACTERISTIC')
            xlabel(app.correctedgraph, 'AI')
            ylabel(app.correctedgraph, 'DO')
            zlabel(app.correctedgraph, 'Z')
            app.errorlabel = uilabel(app.STATICTESTTab);
            app.errorlabel.Text = '';
            app.RESULTSPanel_2 = uipanel(app.STATICTESTTab);
            app.RESULTSPanel_2.Title = 'RESULTS';
            app.textbfGainerrorLabel = uilabel(app.RESULTSPanel_2);
            app.textbfGainerrorLabel.Interpreter = 'latex';
            app.textbfGainerrorLabel.Text = '\textbf{Gain error [\%] :}';
            app.textbfOffseterrorVLabel = uilabel(app.RESULTSPanel_2);
            app.textbfOffseterrorVLabel.Interpreter = 'latex';
            app.textbfOffseterrorVLabel.Text = '\textbf{Offset error [V] :}';
            app.mathbfDNL_maxLSBLabel = uilabel(app.RESULTSPanel_2);
            app.mathbfDNL_maxLSBLabel.Interpreter = 'latex';
            app.mathbfDNL_maxLSBLabel.Text = '$\mathbf{|DNL|_{max} \ [LSB] :}$';
            app.mathbfINL_maxLSBLabel = uilabel(app.RESULTSPanel_2);
            app.mathbfINL_maxLSBLabel.Interpreter = 'latex';
            app.mathbfINL_maxLSBLabel.Text = '$\mathbf{|INL|_{max} \ [LSB] :}$';
            app.Gainsol = uilabel(app.RESULTSPanel_2);
            app.Gainsol.Text = '';
            app.Offsetsol = uilabel(app.RESULTSPanel_2);
            app.Offsetsol.Text = '';
            app.INLSol = uilabel(app.RESULTSPanel_2);
            app.INLSol.Text = '';
            app.DNLSol = uilabel(app.RESULTSPanel_2);
            app.DNLSol.Text = '';
            app.RampslopefracFS1fclk2NBitsMLabel = uilabel(app.STATICTESTTab);
            app.RampslopefracFS1fclk2NBitsMLabel.Interpreter = 'latex';
            app.RampslopefracFS1fclk2NBitsMLabel.Text = 'Ramp slope =  $\frac{FS}{(1/fclk)·(2^{NBits})·(M)}$';
            app.simstate = uilabel(app.STATICTESTTab);
            app.simstate.Text = '';
            app.RampbasedhistogramtestGainOffseterrorINLDNLmissingcodesLabel = uilabel(app.STATICTESTTab);
            app.RampbasedhistogramtestGainOffseterrorINLDNLmissingcodesLabel.Interpreter = 'latex';
            app.RampbasedhistogramtestGainOffseterrorINLDNLmissingcodesLabel.Text = {'Ramp-based histogram test for evaluating:'; 'Gain/Offset error, INL, DNL, missing codes'};
            app.DYNAMICTESTTab = uitab(app.TabGroup);
            app.DYNAMICTESTTab.Title = 'DYNAMIC TEST';
            app.spectfig = uiaxes(app.DYNAMICTESTTab);
            title(app.spectfig, 'SPECTRUM')
            xlabel(app.spectfig, 'Frequency [Hz]')
            ylabel(app.spectfig, 'PSD (dB)')
            zlabel(app.spectfig, 'Z')
            app.Panel_2 = uipanel(app.DYNAMICTESTTab);
            app.HarmonicsPanel = uipanel(app.DYNAMICTESTTab);
            app.HarmonicsPanel.Title = 'Harmonics';
            app.text6 = uilabel(app.HarmonicsPanel);
            app.text6.Text = '';
            app.text5 = uilabel(app.HarmonicsPanel);
            app.text5.Text = '';
            app.text4 = uilabel(app.HarmonicsPanel);
            app.text4.Text = '';
            app.text3 = uilabel(app.HarmonicsPanel);
            app.text3.Text = '';
            app.text2 = uilabel(app.HarmonicsPanel);
            app.text2.Text = '';
            app.simulatedyn = uibutton(app.DYNAMICTESTTab, 'push');
            app.simulatedyn.ButtonPushedFcn = createCallbackFcn(app, @simulatedynButtonPushed, true);
            app.simulatedyn.Text = 'SIMULATE';
            app.Image_3 = uiimage(app.DYNAMICTESTTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'LOGO.png');
            app.SinusoidalSignalPanel = uipanel(app.DYNAMICTESTTab);
            app.SinusoidalSignalPanel.Title = 'Sinusoidal Signal';
            app.ndto5thharmonicsamplitudesVPanel = uipanel(app.SinusoidalSignalPanel);
            app.ndto5thharmonicsamplitudesVPanel.Title = '2nd to 5th harmonics amplitudes [V]';
            app.Ah2EditFieldLabel = uilabel(app.ndto5thharmonicsamplitudesVPanel);
            app.Ah2EditFieldLabel.Interpreter = 'latex';
            app.Ah2EditFieldLabel.Text = 'Ah2';
            app.Ah2 = uieditfield(app.ndto5thharmonicsamplitudesVPanel, 'text');
            app.Ah2.Value = '1*sqrt(2)*3e-5';
            app.Ah4EditFieldLabel = uilabel(app.ndto5thharmonicsamplitudesVPanel);
            app.Ah4EditFieldLabel.Interpreter = 'latex';
            app.Ah4EditFieldLabel.Text = 'Ah4';
            app.Ah4 = uieditfield(app.ndto5thharmonicsamplitudesVPanel, 'text');
            app.Ah4.Value = '1*sqrt(2)*3e-5';
            app.Ah3EditFieldLabel = uilabel(app.ndto5thharmonicsamplitudesVPanel);
            app.Ah3EditFieldLabel.Interpreter = 'latex';
            app.Ah3EditFieldLabel.Text = 'Ah3';
            app.Ah3 = uieditfield(app.ndto5thharmonicsamplitudesVPanel, 'text');
            app.Ah3.Value = '1*sqrt(2)*5e-4';
            app.Ah5EditFieldLabel = uilabel(app.ndto5thharmonicsamplitudesVPanel);
            app.Ah5EditFieldLabel.Interpreter = 'latex';
            app.Ah5EditFieldLabel.Text = 'Ah5';
            app.Ah5 = uieditfield(app.ndto5thharmonicsamplitudesVPanel, 'text');
            app.Ah5.Value = '1*sqrt(2)*1e-4';
            app.white_noise_state = uicheckbox(app.SinusoidalSignalPanel);
            app.white_noise_state.Text = '';
            app.white_noise_state.Value = true;
            app.OffsetVLabel = uilabel(app.SinusoidalSignalPanel);
            app.OffsetVLabel.Interpreter = 'latex';
            app.OffsetVLabel.Text = '\textbf{Offset [V]}';
            app.Xof = uieditfield(app.SinusoidalSignalPanel, 'text');
            app.Xof.Value = 'FS/2';
            app.AmplitudeFundamentalToneVLabel = uilabel(app.SinusoidalSignalPanel);
            app.AmplitudeFundamentalToneVLabel.Interpreter = 'latex';
            app.AmplitudeFundamentalToneVLabel.Text = '\textbf{Amplitude Fundamental Tone [V]}';
            app.Ain = uieditfield(app.SinusoidalSignalPanel, 'text');
            app.Ain.Value = '0.9*(FS/2)';
            app.white_noise = uieditfield(app.SinusoidalSignalPanel, 'text');
            app.white_noise.Value = '1*1e-4';
            app.FrequencyfundamentaltonefinHzLabel = uilabel(app.SinusoidalSignalPanel);
            app.FrequencyfundamentaltonefinHzLabel.Interpreter = 'latex';
            app.FrequencyfundamentaltonefinHzLabel.Text = '\textbf{Frequency fundamental tone, fin [Hz]  }';
            app.fin = uieditfield(app.SinusoidalSignalPanel, 'text');
            app.fin.Value = '0.98 * (1/2) * fs/5';
            app.NoiseLabel_4 = uilabel(app.SinusoidalSignalPanel);
            app.NoiseLabel_4.Interpreter = 'latex';
            app.NoiseLabel_4.Text = 'random ∈ uniform(-Vnoise, Vnoise)';
            app.NoiseLabel_5 = uilabel(app.SinusoidalSignalPanel);
            app.NoiseLabel_5.Interpreter = 'latex';
            app.NoiseLabel_5.Text = '\textbf{Vnoise}';
            app.FFTParametersPanel = uipanel(app.DYNAMICTESTTab);
            app.FFTParametersPanel.Tooltip = {''};
            app.FFTParametersPanel.Title = 'FFT Parameters';
            app.Numberofpointstemporalsequence2NNLabel = uilabel(app.FFTParametersPanel);
            app.Numberofpointstemporalsequence2NNLabel.Tooltip = {'Number of points temporal sequence'};
            app.Numberofpointstemporalsequence2NNLabel.Interpreter = 'latex';
            app.Numberofpointstemporalsequence2NNLabel.Text = 'Time sequence length: 2^{N}. N =';
            app.N = uieditfield(app.FFTParametersPanel, 'numeric');
            app.N.Value = 11;
            app.NbinsaroundcentralbinforpowercomputationLabel = uilabel(app.FFTParametersPanel);
            app.NbinsaroundcentralbinforpowercomputationLabel.Tooltip = {'Number of  bins around central bin (for power computation)'};
            app.NbinsaroundcentralbinforpowercomputationLabel.Interpreter = 'latex';
            app.NbinsaroundcentralbinforpowercomputationLabel.Text = 'Nº bins around central bin ';
            app.span_bins = uieditfield(app.FFTParametersPanel, 'numeric');
            app.span_bins.Value = 10;
            app.WindowfunctionDropDownLabel = uilabel(app.FFTParametersPanel);
            app.WindowfunctionDropDownLabel.Interpreter = 'tex';
            app.WindowfunctionDropDownLabel.Text = 'Window function';
            app.Windowfunction = uidropdown(app.FFTParametersPanel);
            app.Windowfunction.Items = {'Hanning', 'Blackman-Harris', 'Bartlett', 'Chebyshev', 'Hamming', 'Rectangular'};
            app.Windowfunction.Value = 'Hanning';
            app.RESULTSPanel = uipanel(app.DYNAMICTESTTab);
            app.RESULTSPanel.Title = 'RESULTS';
            app.text1 = uilabel(app.RESULTSPanel);
            app.text1.Text = '';
            app.text7 = uilabel(app.RESULTSPanel);
            app.text7.Text = '';
            app.text8 = uilabel(app.RESULTSPanel);
            app.text8.Text = '';
            app.text9 = uilabel(app.RESULTSPanel);
            app.text9.Text = '';
            app.text10 = uilabel(app.RESULTSPanel);
            app.text10.Text = '';
            app.simstate_2 = uilabel(app.DYNAMICTESTTab);
            app.simstate_2.Text = '';
            app.SinewavebasedtestforevaluatingTHDSNRSNDRENOBPsigPnoisePHDLabel = uilabel(app.DYNAMICTESTTab);
            app.SinewavebasedtestforevaluatingTHDSNRSNDRENOBPsigPnoisePHDLabel.Interpreter = 'latex';
            app.SinewavebasedtestforevaluatingTHDSNRSNDRENOBPsigPnoisePHDLabel.Text = {'Sine wave-based test for evaluating:'; 'THD, SNR, SNDR, ENOB, Psig, Pnoise, Phar, PHD'};
            app.Panel = uipanel(app.DYNAMICTESTTab);
            app.textbfSamplingfrequencyfsfracfclk2NBitstextbfHzLabel = uilabel(app.Panel);
            app.textbfSamplingfrequencyfsfracfclk2NBitstextbfHzLabel.Interpreter = 'latex';
            app.textbfSamplingfrequencyfsfracfclk2NBitstextbfHzLabel.Text = '\textbf{Sampling frequency}, $ fs = \frac{fclk}{2^{NBits}}$ \textbf{[Hz] = }';
            app.fs = uilabel(app.Panel);
            app.fs.Text = '3906.25';
            app.ClockJitterPanel = uipanel(app.DYNAMICTESTTab);
            app.ClockJitterPanel.Title = 'Clock Jitter';
            app.ButtonGroup = uibuttongroup(app.ClockJitterPanel);
            app.nsButton = uiradiobutton(app.ButtonGroup);
            app.nsButton.Text = '[ns]';
            app.nsButton.Value = true;
            app.psButton = uiradiobutton(app.ButtonGroup);
            app.psButton.Text = '[ps]';
            app.dyn_impairments_state = uicheckbox(app.ClockJitterPanel);
            app.dyn_impairments_state.Text = 'ENABLED';
            app.Numberofpointstemporalsequence2NNLabel_2 = uilabel(app.ClockJitterPanel);
            app.Numberofpointstemporalsequence2NNLabel_2.Tooltip = {'Number of points temporal sequence'};
            app.Numberofpointstemporalsequence2NNLabel_2.Interpreter = 'latex';
            app.Numberofpointstemporalsequence2NNLabel_2.Text = 'Aperture Jitter';
            app.ap_jitter = uieditfield(app.ClockJitterPanel, 'numeric');
            app.ap_jitter.Value = 20;
            app.RMSValueLabel = uilabel(app.ClockJitterPanel);
            app.RMSValueLabel.Interpreter = 'latex';
            app.RMSValueLabel.Text = '(RMS Value)';
            app.Label_3 = uilabel(app.ClockJitterPanel);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = {'Careful! RMS aperture jitter = '; '(~ Effective peak-to-peak jitter / 10 ) < (1/fclk)\cdot 10 '; 'to ensure simulation results.'};
            app.ComparatorregenerativepolePanel = uipanel(app.DYNAMICTESTTab);
            app.ComparatorregenerativepolePanel.Title = 'Comparator regenerative pole';
            app.regpole_state = uicheckbox(app.ComparatorregenerativepolePanel);
            app.regpole_state.Text = 'ENABLED';
            app.tau_comps1Label = uilabel(app.ComparatorregenerativepolePanel);
            app.tau_comps1Label.Interpreter = 'tex';
            app.tau_comps1Label.Text = '\tau_{comp} [s]';
            app.taucomp = uieditfield(app.ComparatorregenerativepolePanel, 'text');
            app.taucomp.Value = '(1/fclk)/10';
            app.PARAMETRICSIMTab = uitab(app.TabGroup);
            app.PARAMETRICSIMTab.Title = 'PARAMETRIC SIM';
            app.paramfig = uiaxes(app.PARAMETRICSIMTab);
            title(app.paramfig, 'PARAMETRIC SIMULATION')
            xlabel(app.paramfig, 'ADC Impairment')
            ylabel(app.paramfig, 'ENOB (bits)')
            zlabel(app.paramfig, 'Z')
            app.simulateparam = uibutton(app.PARAMETRICSIMTab, 'push');
            app.simulateparam.ButtonPushedFcn = createCallbackFcn(app, @simulateparamButtonPushed, true);
            app.simulateparam.Text = 'SIMULATE';
            app.Image_4 = uiimage(app.PARAMETRICSIMTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'LOGO.png');
            app.Panel_4 = uipanel(app.PARAMETRICSIMTab);
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = {'Parametric simulation:'; 'ENOB vs ADC Impairments'};
            app.ImpairmentSelectionButtonGroup = uibuttongroup(app.PARAMETRICSIMTab);
            app.ImpairmentSelectionButtonGroup.Title = 'Impairment Selection';
            app.Panel_6 = uipanel(app.ImpairmentSelectionButtonGroup);
            app.ComparatorLabel = uilabel(app.Panel_6);
            app.ComparatorLabel.Text = 'Comparator';
            app.Panel_5 = uipanel(app.ImpairmentSelectionButtonGroup);
            app.Offsetcomp = uiradiobutton(app.ImpairmentSelectionButtonGroup);
            app.Offsetcomp.Text = 'Offset [V]';
            app.RampLabel = uilabel(app.ImpairmentSelectionButtonGroup);
            app.RampLabel.Text = 'Ramp';
            app.e3_2 = uiradiobutton(app.ImpairmentSelectionButtonGroup);
            app.e3_2.Text = 'e3 [%]';
            app.e1_2 = uiradiobutton(app.ImpairmentSelectionButtonGroup);
            app.e1_2.Text = 'e1 [%]';
            app.e_off_2 = uiradiobutton(app.ImpairmentSelectionButtonGroup);
            app.e_off_2.Text = 'e_off [V]';
            app.e_off_2.Value = true;
            app.e2_2 = uiradiobutton(app.ImpairmentSelectionButtonGroup);
            app.e2_2.Text = 'e2 [%]';
            app.Noiseramp = uiradiobutton(app.ImpairmentSelectionButtonGroup);
            app.Noiseramp.Text = 'Ramp Noise [V]';
            app.Noisecomp = uiradiobutton(app.ImpairmentSelectionButtonGroup);
            app.Noisecomp.Text = 'Comparator Noise [V]';
            app.start = uieditfield(app.ImpairmentSelectionButtonGroup, 'numeric');
            app.step = uieditfield(app.ImpairmentSelectionButtonGroup, 'numeric');
            app.step.Value = 0.1;
            app.stop = uieditfield(app.ImpairmentSelectionButtonGroup, 'numeric');
            app.stop.Value = 0.5;
            app.StartLabel = uilabel(app.ImpairmentSelectionButtonGroup);
            app.StartLabel.Text = 'Start';
            app.StepLabel = uilabel(app.ImpairmentSelectionButtonGroup);
            app.StepLabel.Text = 'Step';
            app.StopLabel = uilabel(app.ImpairmentSelectionButtonGroup);
            app.StopLabel.Text = 'Stop';
            app.simul_state = uilabel(app.PARAMETRICSIMTab);
            app.simul_state.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SS_ADC_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end